classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SpeakButton                    matlab.ui.control.Button
        SpeakyThing9000EditFieldLabel  matlab.ui.control.Label
        SpeakyThing9000EditField       matlab.ui.control.EditField
        SynthesiseButton               matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        freqDomain                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SpeakButton
        function SpeakButtonPushed(app, event)
            text = convertCharsToStrings(app.SpeakyThing9000EditField.Value);
            b = sentenceToSound(text);
           
            sound(b);
            
            % plot waveform analyser
            plot(app.UIAxes, b);
            
            % plot freq domain
            nfft = 1024;
            f = linspace(0,10000,nfft);
            Y = abs(fft(b,nfft));
            
            plot(app.freqDomain, f(1:nfft/4),Y(1:nfft/4));
            
            audiowrite('sound.wav',b,10000);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpeakButton = uibutton(app.UIFigure, 'push');
            app.SpeakButton.ButtonPushedFcn = createCallbackFcn(app, @SpeakButtonPushed, true);
            app.SpeakButton.Text = 'Speak';
            app.SpeakyThing9000EditFieldLabel = uilabel(app.UIFigure);
            app.SpeakyThing9000EditFieldLabel.Text = 'SpeakyThing9000';
            app.SpeakyThing9000EditField = uieditfield(app.UIFigure, 'text');
            app.SpeakyThing9000EditField.Tooltip = {'Enter Text Here'};
            app.SynthesiseButton = uibutton(app.UIFigure, 'push');
            app.SynthesiseButton.Text = 'Synthesise';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'To use the synthesiser just enter your text in the box above and click synthesise. If you want to listen to it again just click speak.';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform Analyser')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.freqDomain = uiaxes(app.UIFigure);
            title(app.freqDomain, 'Frequency Domain')
            xlabel(app.freqDomain, 'Frequency')
            ylabel(app.freqDomain, 'Absolute Value')
            zlabel(app.freqDomain, 'Z')
            app.freqDomain.XTick = [0 0.2 0.4 0.6 0.8 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end